classdef diffusion_disk_analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        DrawRectangleButton  matlab.ui.control.Button
        EnterSEMfileButton   matlab.ui.control.Button
        UndoButton           matlab.ui.control.Button
        DoneButton           matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end


    properties (Access = private)
        full_file_path = "";
        original_image = [];
        pixel_size = 0;
        drawnRectangles;
        drawnRectanglesPos;
        nanoholeProps;
        diffusionProps;
    end

    methods (Access = private)

        function finalRgbWithLabels = create_final_image_with_ellipses(app)

            if size(app.original_image, 3) == 1
                fullRgb = repmat(app.original_image, [1 1 3]);
            else
                fullRgb = app.original_image;
            end

            fullRgb = im2double(fullRgb);

            for i = 1:numel(app.drawnRectanglesPos)
                rectPos = app.drawnRectanglesPos{i};
                nanoholePropsC = app.nanoholeProps{i};
                diffusionPropsC = app.diffusionProps{i};

                xOffset = rectPos(1) - 1;
                yOffset = rectPos(2) - 1;

                fullRgb = draw_ellipses_on_image(app, fullRgb, nanoholePropsC, xOffset, yOffset, [0 1 0]); % Green nanoholes
                fullRgb = draw_ellipses_on_image(app, fullRgb, diffusionPropsC, xOffset, yOffset, [1 0 0]); % Red diffusion disks
            end

            fig = figure('Name', 'Final Nanohole & Diffusion Disk Ellipses on Full Image', 'NumberTitle', 'off');
            imshow(fullRgb);
            hold on;

            labelCounter = 1;
            for i = 1:numel(app.drawnRectanglesPos)
                rectPos = app.drawnRectanglesPos{i};
                nanoholePropsC = app.nanoholeProps{i};

                xOffset = rectPos(1) - 1;
                yOffset = rectPos(2) - 1;

                for k = 1:length(nanoholePropsC)
                    centroid = nanoholePropsC(k).Centroid;

                    xText = centroid(1) + xOffset;
                    yText = centroid(2) + yOffset;

                    text(xText, yText - 10, sprintf('%d', labelCounter), ...
                        'Color', 'yellow', 'FontSize', 12, 'FontWeight', 'bold', 'HorizontalAlignment', 'center');

                    labelCounter = labelCounter + 1;
                end
            end

            hold off;
            title('Final image with all fitted ellipses and nanohole numbers');

            drawnow;

            frame = getframe(gca); 
            finalRgbWithLabels = frame.cdata;
        end


        function rgbImg = draw_ellipses_on_image(~, rgbImg, props, xOffset, yOffset, color)
            for k = 1:length(props)
                a = props(k).MajorAxisLength / 2;
                b = props(k).MinorAxisLength / 2;
                phi = deg2rad(props(k).Orientation);
                center = props(k).Centroid + [xOffset, yOffset]; 

                theta = linspace(0, 2*pi, 200);
                x_ellipse = a * cos(theta);
                y_ellipse = b * sin(theta);
                R = [cos(phi), -sin(phi); sin(phi), cos(phi)];
                coords = R * [x_ellipse; y_ellipse];

                x_coords = round(coords(1,:) + center(1));
                y_coords = round(coords(2,:) + center(2));

                valid = x_coords >= 1 & x_coords <= size(rgbImg, 2) & ...
                    y_coords >= 1 & y_coords <= size(rgbImg, 1);
                x_coords = x_coords(valid);
                y_coords = y_coords(valid);

                thickness = 2;
                for dx = -thickness:thickness
                    for dy = -thickness:thickness
                        xi = x_coords + dx;
                        yi = y_coords + dy;
                        in_bounds = xi >= 1 & xi <= size(rgbImg, 2) & ...
                            yi >= 1 & yi <= size(rgbImg, 1);
                        xi = xi(in_bounds);
                        yi = yi(in_bounds);
                        idx = sub2ind([size(rgbImg,1), size(rgbImg,2)], yi, xi);
                        for c = 1:3
                            channelValue = color(c);
                            rgbImg_channel = rgbImg(:,:,c);
                            rgbImg_channel(idx) = channelValue;
                            rgbImg(:,:,c) = rgbImg_channel;
                        end
                    end
                end
            end
        end


        function kmeans_analyzer(app, rectPos)
            try
                if size(app.original_image, 3) > 1
                    grayImg = rgb2gray(app.original_image);
                else
                    grayImg = app.original_image;
                end

                x = round(rectPos(1));
                y = round(rectPos(2));
                w = round(rectPos(3));
                h = round(rectPos(4));

                xEnd = min(x + w - 1, size(grayImg, 2));
                yEnd = min(y + h - 1, size(grayImg, 1));
                x = max(x, 1);
                y = max(y, 1);

                cropped = grayImg(y:yEnd, x:xEnd);
                crop_rgb = repmat(cropped, [1 1 3]);

                data = double(cropped(:));
                data = data(~isnan(data));

                [idx, C] = kmeans(data, 2, 'MaxIter', 300, 'Replicates', 5, 'Display', 'off');

                segmented = reshape(idx, size(cropped));

                borderThickness = 2;
                borderMask = false(size(segmented));
                borderMask(1:borderThickness, :) = true;
                borderMask(end-borderThickness+1:end, :) = true;
                borderMask(:, 1:borderThickness) = true;
                borderMask(:, end-borderThickness+1:end) = true;

                borderLabels = segmented(borderMask);
                counts = histcounts(borderLabels, 1:3);

                [~, nanoholeLabel] = max(counts);
                diffusionDiskLabel = 3 - nanoholeLabel;

                nanoholeMask = (segmented == nanoholeLabel);
                nanoholeMask = bwareaopen(nanoholeMask, 50);

                diffusionDiskMask = (segmented == diffusionDiskLabel);
                diffusionDiskMask = bwareaopen(diffusionDiskMask, 50);

                diffusionDiskMaskFilled = imfill(diffusionDiskMask, 'holes');
                diffusionDiskMaskFilled = bwareafilt(diffusionDiskMaskFilled, 1);

                nanohole_mask_modified = diffusionDiskMaskFilled & nanoholeMask;
                nanohole_mask_modified = bwareafilt(nanohole_mask_modified, 1);

                nanoholePropsC = regionprops(nanohole_mask_modified, 'Centroid', 'MajorAxisLength', 'MinorAxisLength', 'Orientation');
                finalrgb = draw_ellipse_on_image(app, crop_rgb, nanoholePropsC, cropped, [0 255 0]);

                diffusionPropsC = regionprops(diffusionDiskMaskFilled, 'Centroid', 'MajorAxisLength', 'MinorAxisLength', 'Orientation');
                finalrgb = draw_ellipse_on_image(app, finalrgb, diffusionPropsC, cropped, [255 0 0]);

                app.drawnRectanglesPos{end+1} = rectPos;
                app.nanoholeProps{end+1} = nanoholePropsC;
                app.diffusionProps{end+1} = diffusionPropsC;


                % Create new figure
                hFig = figure('Name', sprintf('Nanohole & Diffusion Disk with Ellipses (ROI #%d)', numel(app.drawnRectanglesPos)), ...
                    'NumberTitle', 'off', 'Position', [100 100 600 600]);
                hAx = axes(hFig);
                imshow(finalrgb, 'Parent', hAx);
                title(hAx, sprintf('Nanohole (Green) & Diffusion Disk (Red) Masks - ROI #%d', numel(app.drawnRectanglesPos)));

            catch ME
                disp(['Error in kmeans_analyzer: ' ME.message]);
            end
        end

        function rgbImg = draw_ellipse_on_image(~, rgbImg, props, grayImg, color)

            a = props.MajorAxisLength / 2;
            b = props.MinorAxisLength / 2;
            phi = deg2rad(props.Orientation);
            center = props.Centroid;

            theta = linspace(0, 2*pi, 200);
            x = a * cos(theta);
            y = b * sin(theta);
            R = [cos(phi), -sin(phi); sin(phi), cos(phi)];
            coords = R * [x; y];

            x_ellipse = round(coords(1,:) + center(1));
            y_ellipse = round(coords(2,:) + center(2));

            valid = x_ellipse >= 1 & x_ellipse <= size(rgbImg, 2) & ...
                y_ellipse >= 1 & y_ellipse <= size(rgbImg, 1);
            x_ellipse = x_ellipse(valid);
            y_ellipse = y_ellipse(valid);

            thickness = 2;
            for dx = -thickness:thickness
                for dy = -thickness:thickness
                    xi = x_ellipse + dx;
                    yi = y_ellipse + dy;

                    in_bounds = xi >= 1 & xi <= size(rgbImg, 2) & ...
                        yi >= 1 & yi <= size(rgbImg, 1);
                    xi = xi(in_bounds);
                    yi = yi(in_bounds);

                    idx = sub2ind(size(grayImg), yi, xi);
                    rgbImg(idx) = color(1);                            
                    rgbImg(idx + numel(grayImg)) = color(2);           
                    rgbImg(idx + 2*numel(grayImg)) = color(3);         
                end
            end
        end


        function resetAppToInitialState(app)
            
            app.nanoholeProps = {};
            app.diffusionProps = {};
            app.drawnRectanglesPos = {};
            if isfield(app, 'drawnRectangles') && ~isempty(app.drawnRectangles)
                for i = 1:numel(app.drawnRectangles)
                    if isvalid(app.drawnRectangles{i})
                        delete(app.drawnRectangles{i});
                    end
                end
            end

            cla(app.UIAxes); 

            app.EnterSEMfileButton.Visible = "on";
            app.DrawRectangleButton.Visible = "off";
            app.UndoButton.Visible = "off";
            app.DoneButton.Visible = "off";
            app.UIAxes.Visible = "off";
            app.UIAxes.Title.String = "";

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterSEMfileButton
        function EnterSEMfileButtonPushed(app, event)
            try
                [file,location] = uigetfile("*.*");
                figure(app.UIFigure)
                app.UIAxes.Visible = "off";
                app.UIAxes.Visible = "on";
                if isequal(file,0)
                    disp('User selected Cancel');
                else
                    app.full_file_path = fullfile(location,file);
                    app.original_image = imread(app.full_file_path);
                    try
                        info = imfinfo(app.full_file_path);
                        str = info.UnknownTags.Value;
                        parts = strsplit(str, "\n");
                        app.pixel_size = str2double(parts{4})*1e9/2;
                    catch
                        answer = inputdlg("Pixel size per pixel (in nm):", ...
                            "Enter Pixel Size", [1 50]);

                        if isempty(answer)
                            errordlg("Pixel size is required to continue.");
                            return;
                        end

                        user_pixel_nm = str2double(answer{1});

                        if isnan(user_pixel_nm)
                            errordlg("Invalid pixel size input.");
                            return;
                        end

                        app.pixel_size = user_pixel_nm;
                    end
                    imshow(app.original_image, "Parent", app.UIAxes);
                    app.UndoButton.Visible = "on";
                    app.DoneButton.Visible = "on";
                    app.UndoButton.Enable = "off";
                    app.DoneButton.Enable = "off";
                    app.EnterSEMfileButton.Visible = "off";
                    app.DrawRectangleButton.Visible = "on";

                    app.UIAxes.Visible = "on";
                    app.UIAxes.XLabel.String = "";
                    app.UIAxes.YLabel.String = "";
                    app.UIAxes.Title.String = "Draw rectangles around nanoholes (include also diffusion disk and some background!)";
                    app.UIAxes.XTickLabel = {};
                    app.UIAxes.YTickLabel = {};
                    app.UIAxes.XTick = {};
                    app.UIAxes.YTick = {};
                    axis(app.UIAxes, "tight");
                end
            catch
                errordlg("Invalid image file. Please select a valid image.")
            end

        end

        % Button pushed function: DrawRectangleButton
        function DrawRectangleButtonPushed(app, event)
            try
                rect = drawrectangle(app.UIAxes, 'Color', 'r', 'LineWidth', 1.5);

                if isempty(rect) || ~isvalid(rect)

                    return;
                end

                app.drawnRectangles(end+1) = rect;

                app.DoneButton.Enable = "on";
                app.UndoButton.Enable = "on";

                app.kmeans_analyzer(rect.Position);


            catch ME
                uialert(app.UIFigure, ['Error during rectangle drawing: ' ME.message], 'Drawing Error');
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)

            % ----------------------------------------------
            % 0. Extract input file folder + name
            % ----------------------------------------------
            [inputFolder, inputName, ~] = fileparts(app.full_file_path);

            imgOutPath = fullfile(inputFolder, sprintf('%s_fitted_ellipses.png', inputName));
            xlsOutPath = fullfile(inputFolder, sprintf('%s_analysis.xlsx', inputName));

            % ----------------------------------------------
            % 1. Save final image automatically
            finalImage = app.create_final_image_with_ellipses();
            imwrite(finalImage, imgOutPath);

            disp(['Saved fitted ellipse image: ' imgOutPath]);

            % ----------------------------------------------
            % 2. Prepare Excel data
            % ----------------------------------------------
            nanoholePropsC = app.nanoholeProps;
            diffusionPropsC = app.diffusionProps;

            n = length(nanoholePropsC);
            data = zeros(n, 4);

            for i = 1:n
                nh = nanoholePropsC{i};
                dd = diffusionPropsC{i};

                data(i,1) = nh.MajorAxisLength * app.pixel_size;
                data(i,2) = nh.MinorAxisLength * app.pixel_size;
                data(i,3) = ((dd.MajorAxisLength - nh.MajorAxisLength) / 2) * app.pixel_size;
                data(i,4) = ((dd.MinorAxisLength - nh.MinorAxisLength) / 2) * app.pixel_size;
            end

            dataRounded = round(data, 2);

            meanVals = mean(data, 1);
            stdVals  = std(data, 0, 1);

            % Build mean ± std strings
            meanStdStr = strings(1,4);
            for col = 1:4
                meanStdStr(col) = sprintf('%.2f ± %.2f', meanVals(col), stdVals(col));
            end

            % Build Excel output cell array
            nhNumbers = cellstr(string((1:n)'));
            nhNumbers(end+1) = {'Mean ± Std'};

            dataCell = num2cell(dataRounded);
            dataCell(end+1, :) = cellstr(meanStdStr);

            combinedCell = [nhNumbers, dataCell];

            varNames = ['Nanohole #', ...
                {'Nanohole Major Axis Length (nm)', ...
                'Nanohole Minor Axis Length (nm)', ...
                'Diffusion Disk Radius Major (nm)', ...
                'Diffusion Disk Radius Minor (nm)'}];

            xlsCell = [varNames; combinedCell];

            % ----------------------------------------------
            % 3. Save Excel automatically
            % ----------------------------------------------
            writecell(xlsCell, xlsOutPath);

            disp(['Saved analysis Excel file: ' xlsOutPath]);

            % Reset app
            resetAppToInitialState(app);


        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if ~isempty(app.drawnRectangles)
                delete(app.drawnRectangles(end));
                app.drawnRectanglesPos(end) = [];

                if ~isempty(app.nanoholeProps)
                    app.nanoholeProps(end) = [];
                end

                if ~isempty(app.diffusionProps)
                    app.diffusionProps(end) = [];
                end

            else
                disp('No rectangles to undo.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Visible = 'off';
            app.DoneButton.Text = 'Done';
            app.UndoButton = uibutton(app.GridLayout, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Visible = 'off';
            app.UndoButton.Text = 'Undo';
            app.EnterSEMfileButton = uibutton(app.GridLayout, 'push');
            app.EnterSEMfileButton.ButtonPushedFcn = createCallbackFcn(app, @EnterSEMfileButtonPushed, true);
            app.EnterSEMfileButton.Text = 'Enter SEM file';
            app.DrawRectangleButton = uibutton(app.GridLayout, 'push');
            app.DrawRectangleButton.ButtonPushedFcn = createCallbackFcn(app, @DrawRectangleButtonPushed, true);
            app.DrawRectangleButton.Visible = 'off';
            app.DrawRectangleButton.Text = 'Draw Rectangle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = diffusion_disk_analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end